classdef app1_lab < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        generalTab                      matlab.ui.container.Tab
        organizerandJenniferPanel       matlab.ui.container.Panel
        EnterelectrodesfileEditField_2  matlab.ui.control.EditField
        EnterelectrodesfileEditField_2Label  matlab.ui.control.Label
        EnterdatafileEditField_2        matlab.ui.control.EditField
        EnterdatafileEditField_2Label   matlab.ui.control.Label
        runthispartCheckBox             matlab.ui.control.CheckBox
        statisticsandplotsPanel         matlab.ui.container.Panel
        DONEButton                      matlab.ui.control.Button
        showcolumnsnamesButton          matlab.ui.control.Button
        ExportingformatDropDown         matlab.ui.control.DropDown
        ExportingformatDropDownLabel    matlab.ui.control.Label
        EnterdatafileEditField          matlab.ui.control.EditField
        EnterdatafileEditFieldLabel     matlab.ui.control.Label
        JenniferTab                     matlab.ui.container.Tab
        REMINDERnoledno_ledone_beforethewordledLabel  matlab.ui.control.Label
        grayButton_3                    matlab.ui.control.Button
        pinkButton_3                    matlab.ui.control.Button
        purpleButton_3                  matlab.ui.control.Button
        cyanButton_3                    matlab.ui.control.Button
        blueButton_3                    matlab.ui.control.Button
        greenButton_3                   matlab.ui.control.Button
        orangeButton_3                  matlab.ui.control.Button
        redButton_3                     matlab.ui.control.Button
        entercolorssepbyEditField_3     matlab.ui.control.EditField
        entercolorssepbyEditField_3Label  matlab.ui.control.Label
        UITable_4                       matlab.ui.control.Table
        sipsTab                         matlab.ui.container.Tab
        sizeofmultiplotexportedfileLabel  matlab.ui.control.Label
        widthSpinner                    matlab.ui.control.Spinner
        widthSpinnerLabel               matlab.ui.control.Label
        heigthSpinner                   matlab.ui.control.Spinner
        heigthSpinnerLabel              matlab.ui.control.Label
        grayButton                      matlab.ui.control.Button
        pinkButton                      matlab.ui.control.Button
        purpleButton                    matlab.ui.control.Button
        cyanButton                      matlab.ui.control.Button
        blueButton                      matlab.ui.control.Button
        greenButton                     matlab.ui.control.Button
        orangeButton                    matlab.ui.control.Button
        redButton                       matlab.ui.control.Button
        ytitlePanel                     matlab.ui.container.Panel
        boldCheckBox_3                  matlab.ui.control.CheckBox
        sizeSlider_3                    matlab.ui.control.Slider
        sizeSlider_3Label               matlab.ui.control.Label
        fontDropDown_3                  matlab.ui.control.DropDown
        fontDropDown_3Label             matlab.ui.control.Label
        xtitlePanel                     matlab.ui.container.Panel
        boldCheckBox_2                  matlab.ui.control.CheckBox
        sizeSlider_2                    matlab.ui.control.Slider
        sizeSlider_2Label               matlab.ui.control.Label
        fontDropDown_2                  matlab.ui.control.DropDown
        fontDropDown_2Label             matlab.ui.control.Label
        entercolorssepbyEditField       matlab.ui.control.EditField
        entercolorssepbyEditFieldLabel  matlab.ui.control.Label
        entercolsnamessepbyEditField    matlab.ui.control.EditField
        entercolsnamessepbyLabel        matlab.ui.control.Label
        UITable_2                       matlab.ui.control.Table
        graphsinlineSpinner             matlab.ui.control.Spinner
        graphsinlineSpinnerLabel        matlab.ui.control.Label
        outliersCheckBox                matlab.ui.control.CheckBox
        PITab                           matlab.ui.container.Tab
        grayButton_2                    matlab.ui.control.Button
        pinkButton_2                    matlab.ui.control.Button
        purpleButton_2                  matlab.ui.control.Button
        cyanButton_2                    matlab.ui.control.Button
        blueButton_2                    matlab.ui.control.Button
        greenButton_2                   matlab.ui.control.Button
        orangeButton_2                  matlab.ui.control.Button
        redButton_2                     matlab.ui.control.Button
        lowersubtitleEditField_2        matlab.ui.control.EditField
        lowersubtitleEditField_2Label   matlab.ui.control.Label
        uppersubtitleEditField_2        matlab.ui.control.EditField
        uppersubtitleEditField_2Label   matlab.ui.control.Label
        ytitlePanel_2                   matlab.ui.container.Panel
        boldCheckBox_5                  matlab.ui.control.CheckBox
        sizeSlider_5                    matlab.ui.control.Slider
        sizeSlider_5Label               matlab.ui.control.Label
        fontDropDown_5                  matlab.ui.control.DropDown
        fontDropDown_5Label             matlab.ui.control.Label
        xtitlePanel_2                   matlab.ui.container.Panel
        boldCheckBox_4                  matlab.ui.control.CheckBox
        sizeSlider_4                    matlab.ui.control.Slider
        sizeSlider_4Label               matlab.ui.control.Label
        fontDropDown_4                  matlab.ui.control.DropDown
        fontDropDown_4Label             matlab.ui.control.Label
        entercolorssepbyEditField_2     matlab.ui.control.EditField
        entercolorssepbyEditField_2Label  matlab.ui.control.Label
        entercolsnamessepbyEditField_2  matlab.ui.control.EditField
        entercolsnamessepbyEditField_2Label  matlab.ui.control.Label
        UITable_3                       matlab.ui.control.Table
        splittitleCheckBox              matlab.ui.control.CheckBox
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: DONEButton
        function DONEButtonPushed(app, event)
            % _______ general _______
            input_path = string(app.EnterdatafileEditField.Value);
            input_path = replace(input_path,"\","\\");
            output_path = string(app.ExportingformatDropDown.Value);

            org_file = string(app.EnterdatafileEditField_2.Value);
            org_file = replace(org_file,"\","\\");
            elec_file = string(app.EnterelectrodesfileEditField_2.Value);
            elec_file = replace(elec_file,"\","\\");
            to_do_org = app.runthispartCheckBox.Value;

            % ________ sips + multi ________
            sips_cols_names = string(app.entercolsnamessepbyEditField.Value);
            sips_colors = string(app.entercolorssepbyEditField.Value);
            graphs_in_line = app.graphsinlineSpinner.Value;
            outliers = app.outliersCheckBox.Value;
            multi_width = app.widthSpinner.Value;
            multi_heigth = app.heigthSpinner.Value;

            % x title
            sips_x_size = app.sizeSlider_2.Value;
            sips_x_font = string(app.fontDropDown_2.Value);
            sips_is_x_bold = app.boldCheckBox_2.Value;

            % y title
            sips_y_size = app.sizeSlider_3.Value;
            sips_y_font = string(app.fontDropDown_3.Value);
            sips_is_y_bold = app.boldCheckBox_3.Value;

            % _________ PI ____________
            PI_cols_names = string(app.entercolsnamessepbyEditField_2.Value);
            PI_colors = string(app.entercolorssepbyEditField_2.Value);
            upper_subs = string(app.uppersubtitleEditField_2.Value);
            lower_subs = string(app.lowersubtitleEditField_2.Value);

            % x title
            PI_x_size = app.sizeSlider_4.Value;
            PI_x_font = string(app.fontDropDown_4.Value);
            PI_is_x_bold = app.boldCheckBox_4.Value;
         
            % y title
            PI_y_size = app.sizeSlider_5.Value;
            PI_y_font = string(app.fontDropDown_5.Value);
            PI_is_y_bold = app.boldCheckBox_5.Value;

            % _______stat ____________
            stat_colors = string(app.entercolorssepbyEditField_3.Value);

            % conclusion
            data = table(input_path, output_path, org_file, elec_file, to_do_org, ...
            sips_cols_names, sips_colors, graphs_in_line, outliers, ...
            multi_width, multi_heigth, sips_x_size, sips_x_font, sips_is_x_bold, ...
            sips_y_size, sips_y_font, sips_is_y_bold, ...
            PI_cols_names, PI_colors, upper_subs, lower_subs, ...
            PI_x_size ,PI_x_font , PI_is_x_bold, PI_y_size, PI_y_font, PI_is_y_bold, ...
            stat_colors);
            writetable(data, "C:\Users\labophir\Documents\matlab_to_R_GUI_data.xls");
        end

        % Value changed function: EnterdatafileEditField
        function EnterdatafileEditFieldValueChanged(app, event)
            input_path = app.EnterdatafileEditField.Value;
        end

        % Callback function
        function showcolumnsButtonPushed(app, event)

        end

        % Button pushed function: showcolumnsnamesButton
        function showcolumnsnamesButtonPushed(app, event)
            [data,header] = xlsread(app.EnterdatafileEditField.Value,"NumberOfSips");
            app.UITable_2.Data = header;
            app.UITable_3.Data = header;

            % show sheets names
            if app.EnterdatafileEditField_2.Value ~= ""
                sheets = sheetnames(app.EnterdatafileEditField_2.Value);
                if sheets(1) == "Sheet1"
                    sheets = sheets(2:length(sheets));
                end
                sheets = transpose(cellstr(sheets));
                app.UITable_4.Data = sheets;
            end
        end

        % Button pushed function: redButton
        function redButtonPushed(app, event)

        end

        % Button pushed function: orangeButton
        function orangeButtonPushed(app, event)

        end

        % Value changing function: EnterdatafileEditField
        function EnterdatafileEditFieldValueChanging(app, event)
            changingValue = event.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.splittitleCheckBox = uicheckbox(app.UIFigure);
            app.splittitleCheckBox.Text = 'split title';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.generalTab = uitab(app.TabGroup);
            app.generalTab.Title = 'general';
            app.statisticsandplotsPanel = uipanel(app.generalTab);
            app.statisticsandplotsPanel.Title = 'statistics and plots';
            app.EnterdatafileEditFieldLabel = uilabel(app.statisticsandplotsPanel);
            app.EnterdatafileEditFieldLabel.Text = 'Enter data file';
            app.EnterdatafileEditField = uieditfield(app.statisticsandplotsPanel, 'text');
            app.EnterdatafileEditField.ValueChangedFcn = createCallbackFcn(app, @EnterdatafileEditFieldValueChanged, true);
            app.EnterdatafileEditField.ValueChangingFcn = createCallbackFcn(app, @EnterdatafileEditFieldValueChanging, true);
            app.ExportingformatDropDownLabel = uilabel(app.statisticsandplotsPanel);
            app.ExportingformatDropDownLabel.Text = 'Exporting format';
            app.ExportingformatDropDown = uidropdown(app.statisticsandplotsPanel);
            app.ExportingformatDropDown.Items = {'pdf', 'jpg'};
            app.ExportingformatDropDown.Value = 'pdf';
            app.showcolumnsnamesButton = uibutton(app.statisticsandplotsPanel, 'push');
            app.showcolumnsnamesButton.ButtonPushedFcn = createCallbackFcn(app, @showcolumnsnamesButtonPushed, true);
            app.showcolumnsnamesButton.Text = 'show columns names';
            app.DONEButton = uibutton(app.statisticsandplotsPanel, 'push');
            app.DONEButton.ButtonPushedFcn = createCallbackFcn(app, @DONEButtonPushed, true);
            app.DONEButton.Text = 'DONE';
            app.organizerandJenniferPanel = uipanel(app.generalTab);
            app.organizerandJenniferPanel.Title = 'organizer and Jennifer';
            app.runthispartCheckBox = uicheckbox(app.organizerandJenniferPanel);
            app.runthispartCheckBox.Text = 'run this part';
            app.runthispartCheckBox.Value = true;
            app.EnterdatafileEditField_2Label = uilabel(app.organizerandJenniferPanel);
            app.EnterdatafileEditField_2Label.Text = 'Enter data file';
            app.EnterdatafileEditField_2 = uieditfield(app.organizerandJenniferPanel, 'text');
            app.EnterelectrodesfileEditField_2Label = uilabel(app.organizerandJenniferPanel);
            app.EnterelectrodesfileEditField_2Label.Text = 'Enter electrodes file';
            app.EnterelectrodesfileEditField_2 = uieditfield(app.organizerandJenniferPanel, 'text');
            app.JenniferTab = uitab(app.TabGroup);
            app.JenniferTab.Title = 'Jennifer';
            app.UITable_4 = uitable(app.JenniferTab);
            app.UITable_4.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'; 'Column 5'; 'Column 6'; 'Column 7'; 'Column 8'};
            app.UITable_4.RowName = {};
            app.entercolorssepbyEditField_3Label = uilabel(app.JenniferTab);
            app.entercolorssepbyEditField_3Label.Text = 'enter colors (sep by ",")';
            app.entercolorssepbyEditField_3 = uieditfield(app.JenniferTab, 'text');
            app.entercolorssepbyEditField_3.Value = 'red, orange, green, blue, cyan, purple, pink, gray';
            app.redButton_3 = uibutton(app.JenniferTab, 'push');
            app.redButton_3.Text = 'red';
            app.orangeButton_3 = uibutton(app.JenniferTab, 'push');
            app.orangeButton_3.Text = 'orange';
            app.greenButton_3 = uibutton(app.JenniferTab, 'push');
            app.greenButton_3.Text = 'green';
            app.blueButton_3 = uibutton(app.JenniferTab, 'push');
            app.blueButton_3.Text = 'blue';
            app.cyanButton_3 = uibutton(app.JenniferTab, 'push');
            app.cyanButton_3.Text = 'cyan';
            app.purpleButton_3 = uibutton(app.JenniferTab, 'push');
            app.purpleButton_3.Text = 'purple';
            app.pinkButton_3 = uibutton(app.JenniferTab, 'push');
            app.pinkButton_3.Text = 'pink';
            app.grayButton_3 = uibutton(app.JenniferTab, 'push');
            app.grayButton_3.Text = 'gray';
            app.REMINDERnoledno_ledone_beforethewordledLabel = uilabel(app.JenniferTab);
            app.REMINDERnoledno_ledone_beforethewordledLabel.Text = 'REMINDER: no led - no "_" | led - one "_" before the word "led"';
            app.sipsTab = uitab(app.TabGroup);
            app.sipsTab.Title = 'sips';
            app.outliersCheckBox = uicheckbox(app.sipsTab);
            app.outliersCheckBox.Text = 'outliers';
            app.graphsinlineSpinnerLabel = uilabel(app.sipsTab);
            app.graphsinlineSpinnerLabel.Text = 'graphs in line';
            app.graphsinlineSpinner = uispinner(app.sipsTab);
            app.graphsinlineSpinner.Limits = [2 5];
            app.graphsinlineSpinner.Value = 3;
            app.UITable_2 = uitable(app.sipsTab);
            app.UITable_2.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'; 'Column 5'; 'Column 6'; 'Column 7'; 'Column 8'};
            app.UITable_2.RowName = {};
            app.entercolsnamessepbyLabel = uilabel(app.sipsTab);
            app.entercolsnamessepbyLabel.Text = 'enter cols names (sep by ",")';
            app.entercolsnamessepbyEditField = uieditfield(app.sipsTab, 'text');
            app.entercolorssepbyEditFieldLabel = uilabel(app.sipsTab);
            app.entercolorssepbyEditFieldLabel.Text = 'enter colors (sep by ",")';
            app.entercolorssepbyEditField = uieditfield(app.sipsTab, 'text');
            app.entercolorssepbyEditField.Value = 'red, orange, green, blue, cyan, purple';
            app.xtitlePanel = uipanel(app.sipsTab);
            app.xtitlePanel.Title = 'x title';
            app.fontDropDown_2Label = uilabel(app.xtitlePanel);
            app.fontDropDown_2Label.Text = 'font';
            app.fontDropDown_2 = uidropdown(app.xtitlePanel);
            app.fontDropDown_2.Items = {'sans', 'serif', 'mono'};
            app.fontDropDown_2.Value = 'sans';
            app.sizeSlider_2Label = uilabel(app.xtitlePanel);
            app.sizeSlider_2Label.Text = 'size';
            app.sizeSlider_2 = uislider(app.xtitlePanel);
            app.sizeSlider_2.Limits = [5 20];
            app.sizeSlider_2.Value = 12;
            app.boldCheckBox_2 = uicheckbox(app.xtitlePanel);
            app.boldCheckBox_2.Text = 'bold';
            app.ytitlePanel = uipanel(app.sipsTab);
            app.ytitlePanel.Title = 'y title';
            app.fontDropDown_3Label = uilabel(app.ytitlePanel);
            app.fontDropDown_3Label.Text = 'font';
            app.fontDropDown_3 = uidropdown(app.ytitlePanel);
            app.fontDropDown_3.Items = {'sans', 'serif', 'mono'};
            app.fontDropDown_3.Value = 'sans';
            app.sizeSlider_3Label = uilabel(app.ytitlePanel);
            app.sizeSlider_3Label.Text = 'size';
            app.sizeSlider_3 = uislider(app.ytitlePanel);
            app.sizeSlider_3.Limits = [5 20];
            app.sizeSlider_3.Value = 12;
            app.boldCheckBox_3 = uicheckbox(app.ytitlePanel);
            app.boldCheckBox_3.Text = 'bold';
            app.redButton = uibutton(app.sipsTab, 'push');
            app.redButton.ButtonPushedFcn = createCallbackFcn(app, @redButtonPushed, true);
            app.redButton.Text = 'red';
            app.orangeButton = uibutton(app.sipsTab, 'push');
            app.orangeButton.ButtonPushedFcn = createCallbackFcn(app, @orangeButtonPushed, true);
            app.orangeButton.Text = 'orange';
            app.greenButton = uibutton(app.sipsTab, 'push');
            app.greenButton.Text = 'green';
            app.blueButton = uibutton(app.sipsTab, 'push');
            app.blueButton.Text = 'blue';
            app.cyanButton = uibutton(app.sipsTab, 'push');
            app.cyanButton.Text = 'cyan';
            app.purpleButton = uibutton(app.sipsTab, 'push');
            app.purpleButton.Text = 'purple';
            app.pinkButton = uibutton(app.sipsTab, 'push');
            app.pinkButton.Text = 'pink';
            app.grayButton = uibutton(app.sipsTab, 'push');
            app.grayButton.Text = 'gray';
            app.heigthSpinnerLabel = uilabel(app.sipsTab);
            app.heigthSpinnerLabel.Text = 'heigth';
            app.heigthSpinner = uispinner(app.sipsTab);
            app.heigthSpinner.Limits = [8 30];
            app.heigthSpinner.Value = 20;
            app.widthSpinnerLabel = uilabel(app.sipsTab);
            app.widthSpinnerLabel.Text = 'width';
            app.widthSpinner = uispinner(app.sipsTab);
            app.widthSpinner.Limits = [8 20];
            app.widthSpinner.Value = 10;
            app.sizeofmultiplotexportedfileLabel = uilabel(app.sipsTab);
            app.sizeofmultiplotexportedfileLabel.Text = 'size of multi plot exported file';
            app.PITab = uitab(app.TabGroup);
            app.PITab.Title = 'PI';
            app.UITable_3 = uitable(app.PITab);
            app.UITable_3.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'; 'Column 5'; 'Column 6'; 'Column 7'; 'Column 8'};
            app.UITable_3.RowName = {};
            app.entercolsnamessepbyEditField_2Label = uilabel(app.PITab);
            app.entercolsnamessepbyEditField_2Label.Text = 'enter cols names (sep by ",")';
            app.entercolsnamessepbyEditField_2 = uieditfield(app.PITab, 'text');
            app.entercolorssepbyEditField_2Label = uilabel(app.PITab);
            app.entercolorssepbyEditField_2Label.Text = 'enter colors (sep by ",")';
            app.entercolorssepbyEditField_2 = uieditfield(app.PITab, 'text');
            app.entercolorssepbyEditField_2.Value = 'red, blue, pink, cyan';
            app.xtitlePanel_2 = uipanel(app.PITab);
            app.xtitlePanel_2.Title = 'x title';
            app.fontDropDown_4Label = uilabel(app.xtitlePanel_2);
            app.fontDropDown_4Label.Text = 'font';
            app.fontDropDown_4 = uidropdown(app.xtitlePanel_2);
            app.fontDropDown_4.Items = {'sans', 'serif', 'mono'};
            app.fontDropDown_4.Value = 'sans';
            app.sizeSlider_4Label = uilabel(app.xtitlePanel_2);
            app.sizeSlider_4Label.Text = 'size';
            app.sizeSlider_4 = uislider(app.xtitlePanel_2);
            app.sizeSlider_4.Limits = [5 20];
            app.sizeSlider_4.Value = 12;
            app.boldCheckBox_4 = uicheckbox(app.xtitlePanel_2);
            app.boldCheckBox_4.Text = 'bold';
            app.ytitlePanel_2 = uipanel(app.PITab);
            app.ytitlePanel_2.Title = 'y title';
            app.fontDropDown_5Label = uilabel(app.ytitlePanel_2);
            app.fontDropDown_5Label.Text = 'font';
            app.fontDropDown_5 = uidropdown(app.ytitlePanel_2);
            app.fontDropDown_5.Items = {'sans', 'serif', 'mono'};
            app.fontDropDown_5.Value = 'sans';
            app.sizeSlider_5Label = uilabel(app.ytitlePanel_2);
            app.sizeSlider_5Label.Text = 'size';
            app.sizeSlider_5 = uislider(app.ytitlePanel_2);
            app.sizeSlider_5.Limits = [5 20];
            app.sizeSlider_5.Value = 12;
            app.boldCheckBox_5 = uicheckbox(app.ytitlePanel_2);
            app.boldCheckBox_5.Text = 'bold';
            app.uppersubtitleEditField_2Label = uilabel(app.PITab);
            app.uppersubtitleEditField_2Label.Text = 'upper subtitle';
            app.uppersubtitleEditField_2 = uieditfield(app.PITab, 'text');
            app.uppersubtitleEditField_2.Value = 'Agar';
            app.lowersubtitleEditField_2Label = uilabel(app.PITab);
            app.lowersubtitleEditField_2Label.Text = 'lower subtitle';
            app.lowersubtitleEditField_2 = uieditfield(app.PITab, 'text');
            app.lowersubtitleEditField_2.Value = 'AgarLED';
            app.redButton_2 = uibutton(app.PITab, 'push');
            app.redButton_2.Text = 'red';
            app.orangeButton_2 = uibutton(app.PITab, 'push');
            app.orangeButton_2.Text = 'orange';
            app.greenButton_2 = uibutton(app.PITab, 'push');
            app.greenButton_2.Text = 'green';
            app.blueButton_2 = uibutton(app.PITab, 'push');
            app.blueButton_2.Text = 'blue';
            app.cyanButton_2 = uibutton(app.PITab, 'push');
            app.cyanButton_2.Text = 'cyan';
            app.purpleButton_2 = uibutton(app.PITab, 'push');
            app.purpleButton_2.Text = 'purple';
            app.pinkButton_2 = uibutton(app.PITab, 'push');
            app.pinkButton_2.Text = 'pink';
            app.grayButton_2 = uibutton(app.PITab, 'push');
            app.grayButton_2.Text = 'gray';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1_lab

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end